classdef ddeSolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        EquationTab           matlab.ui.container.Tab
        parametersLabel       matlab.ui.control.Label
        CEditField            matlab.ui.control.NumericEditField
        CEditFieldLabel       matlab.ui.control.Label
        BEditField            matlab.ui.control.NumericEditField
        BEditFieldLabel       matlab.ui.control.Label
        AEditField            matlab.ui.control.NumericEditField
        AEditFieldLabel       matlab.ui.control.Label
        EquationDropDown      matlab.ui.control.DropDown
        RwnanieDropDownLabel  matlab.ui.control.Label
        MethodTab             matlab.ui.container.Tab
        VersionDropDown       matlab.ui.control.DropDown
        VersionLabel          matlab.ui.control.Label
        OrderDropDown         matlab.ui.control.DropDown
        RzdLabel              matlab.ui.control.Label
        MethodDropDown        matlab.ui.control.DropDown
        MetodaLabel           matlab.ui.control.Label
        ClearButton           matlab.ui.control.Button
        ListBox               matlab.ui.control.ListBox
        ListBoxLabel          matlab.ui.control.Label
        SolveButton           matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        phi         % the initial condition
        f_dde       % the delay differential equation
        f_sol       % the known (reference) solution

        h           % the step of method
        k           % the order of method
        tk          % the end time of the desired solution
        Xtau        % the delay on x
        dXtau       % the delay on dx 

    end
    
    methods (Access = private)
        
        function setEquation(app)
            value = app.EquationDropDown.Value;
            switch  value
                case "x'(t) = A · x(t - B), φ(t)=C" % TODO change it to id no string
                    A = app.AEditField.Value;
                    B = app.BEditField.Value;
                    C = app.CEditField.Value;
                    app.dXtau = 0; 
                    app.Xtau = B;
                    app.phi = exampleRddeFunctions.get_phi1(C);
                    app.f_dde = exampleRddeFunctions.get_f_ode1(A, B, C);
                    app.f_sol = exampleRddeFunctions.get_sol1(A, B, C);
                case "x'(t) = x(t) + x(t - 1) -1/4 · x'(t - 1), φ(t)=-t"
                    app.phi = exampleNddeFunctions.get_phi1();
                    app.f_dde = exampleNddeFunctions.get_f_ode1();
                    app.f_sol = exampleNddeFunctions.get_sol1();
                    app.dXtau = 1; 
                    app.Xtau = 1;
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.h = 0.01; % TODO read the step
            app.k = 2;
            app.tk = 6;
            
            A = app.AEditField.Value;
            B = app.BEditField.Value;
            C = app.CEditField.Value;
            
            app.dXtau = 0;
            app.Xtau = B;
            app.phi = exampleRddeFunctions.get_phi1(C);
            app.f_dde = exampleRddeFunctions.get_f_ode1(A, B, C);
            app.f_sol = exampleRddeFunctions.get_sol1(A, B, C);

            %  method window initialization
            app.VersionDropDown.Visible = "off";
            app.VersionLabel.Visible = "off";
            
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            
            % Set equation
            setEquation(app)

            % Set method
            switch app.MethodDropDown.Value
                case 'Explicit Adams'
                    [t_adamsS, x_adamsS] = explicitAdamsSolver(app.k, app.h, app.tk, app.f_dde, app.Xtau, app.dXtau, app.phi);
                case 'Implicit Adams'
                    [t_adamsS, x_adamsS] = implicitAdamsSolver(app.k, app.h, app.tk, app.f_dde, app.Xtau, app.dXtau, app.phi);
                case 'Runge-Kutta'
                    [t_adamsS, x_adamsS] = rungeKuttaSolver(app.k, app.h, app.tk, app.f_dde, app.Xtau, app.dXtau, app.phi);
                case 'Step Method'
                    [t_adamsS, x_adamsS] = stepMethodSolver(app.tk, app.f_dde, app.Xtau, app.dXtau, app.phi);
            end


            t = t_adamsS;
            x = x_adamsS;
            plot(app.UIAxes, t, x);

        end

        % Value changed function: OrderDropDown
        function OrderDropDownValueChanged(app, event)
            app.k = str2double(app.OrderDropDown.Value);
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            value = app.MethodDropDown.Value;
            if value == "Step Method"
                app.OrderDropDown.Visible = "off";
                app.RzdLabel.Visible = "off";
                app.VersionDropDown.Visible = "off";
                app.VersionLabel.Visible = "off";
            elseif value == "Runge-Kutta"
                app.OrderDropDown.Visible = "off";
                app.RzdLabel.Visible = "off";
                app.VersionDropDown.Visible = "on";
                app.VersionLabel.Visible = "on";
            else
                app.OrderDropDown.Visible = "on";
                app.RzdLabel.Visible = "on";
                app.VersionDropDown.Visible = "off";
                app.VersionLabel.Visible = "off";
            end
        end

        % Value changed function: VersionDropDown
        function VersionDropDownValueChanged(app, event)
            value = app.VersionDropDown.Value;
        switch value
            case 'RK1'
                app.k=1;
            case 'RK2'
                app.k=2;
            case 'RK4'
                app.k=4;
            case 'RK3/8'
                app.k=5;
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Rozwiązanie równania')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'x')
            zlabel(app.UIAxes, 'Z')
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = '=';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {''};
            app.ListBox.Value = '';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.Text = 'Wyczyść';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EquationTab = uitab(app.TabGroup);
            app.EquationTab.Title = 'Równanie';
            app.RwnanieDropDownLabel = uilabel(app.EquationTab);
            app.RwnanieDropDownLabel.Text = 'Równanie';
            app.EquationDropDown = uidropdown(app.EquationTab);
            app.EquationDropDown.Items = {'x''(t) = A · x(t - B), φ(t)=C', 'x''(t) = x(t) + x(t - 1) -1/4 · x''(t - 1), φ(t)=-t'};
            app.EquationDropDown.Value = 'x''(t) = A · x(t - B), φ(t)=C';
            app.AEditFieldLabel = uilabel(app.EquationTab);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.EquationTab, 'numeric');
            app.AEditField.Value = 1;
            app.BEditFieldLabel = uilabel(app.EquationTab);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.EquationTab, 'numeric');
            app.BEditField.Limits = [0 Inf];
            app.BEditField.Value = 2;
            app.CEditFieldLabel = uilabel(app.EquationTab);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.EquationTab, 'numeric');
            app.CEditField.Value = 3;
            app.parametersLabel = uilabel(app.EquationTab);
            app.parametersLabel.Text = 'Parametry';
            app.MethodTab = uitab(app.TabGroup);
            app.MethodTab.Title = 'Metoda';
            app.MetodaLabel = uilabel(app.MethodTab);
            app.MetodaLabel.Text = 'Metoda    ';
            app.MethodDropDown = uidropdown(app.MethodTab);
            app.MethodDropDown.Items = {'Explicit Adams', 'Implicit Adams', 'Runge-Kutta', 'Step Method'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Explicit Adams';
            app.RzdLabel = uilabel(app.MethodTab);
            app.RzdLabel.Text = 'Rząd    ';
            app.OrderDropDown = uidropdown(app.MethodTab);
            app.OrderDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.OrderDropDown.ValueChangedFcn = createCallbackFcn(app, @OrderDropDownValueChanged, true);
            app.OrderDropDown.Value = '2';
            app.VersionLabel = uilabel(app.MethodTab);
            app.VersionLabel.Text = 'Wersja';
            app.VersionDropDown = uidropdown(app.MethodTab);
            app.VersionDropDown.Items = {'RK1', 'RK2', 'RK4', 'RK3/8'};
            app.VersionDropDown.ValueChangedFcn = createCallbackFcn(app, @VersionDropDownValueChanged, true);
            app.VersionDropDown.Value = 'RK1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ddeSolver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end