classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        ChoosetheerrorcriterionDropDown  matlab.ui.control.DropDown
        ChoosetheerrorcriterionDropDownLabel  matlab.ui.control.Label
        StepValueEditField        matlab.ui.control.NumericEditField
        StepValueEditFieldLabel   matlab.ui.control.Label
        StepTimeEditField         matlab.ui.control.NumericEditField
        StepTimeEditFieldLabel    matlab.ui.control.Label
        ImportfileButton          matlab.ui.control.Button
        ChooseinputmetodDropDown  matlab.ui.control.DropDown
        ChooseinputmetodLabel     matlab.ui.control.Label
        ZeropolegainmodelLabel    matlab.ui.control.Label
        Info                      matlab.ui.control.Image
        Denominator               matlab.ui.control.EditField
        Label                     matlab.ui.control.Label
        TransferfunctionLabel     matlab.ui.control.Label
        Numerator                 matlab.ui.control.EditField
        ChoosemetodDropDown       matlab.ui.control.DropDown
        ChoosemetodDropDownLabel  matlab.ui.control.Label
        ShowresultsButton         matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        ErrorLabel                matlab.ui.control.Label
        ErrorValue                matlab.ui.control.EditField
        Delay                     matlab.ui.control.EditField
        s_delay                   matlab.ui.control.Label
        e_delay                   matlab.ui.control.Label
        s_time_const              matlab.ui.control.Label
        Time_const                matlab.ui.control.EditField
        Fraction_bar              matlab.ui.control.Label
        Gain                      matlab.ui.control.EditField
        ResultLabel               matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        k  
        T  
        To
        sys
        du
        filename
    end
    
    methods (Access = private)
        
        function matrix = string2matrix_tf(app, str)
            split = str; 
            matrix = [];
            last_exp = -1;
            current_exp=-1;
            
            
            for i = 1:length(split)
                splitten = split{i};  
                exp_start_pos = strfind(splitten, '^');
                s_pos = strfind(splitten, 's');
            
                if ~isempty(s_pos)
                    if ~isempty(exp_start_pos)
                        exp_start_pos = exp_start_pos(1);  
                        current_exp = str2double(splitten(exp_start_pos+1:end));
                
                        if last_exp ~= -1 && last_exp ~= current_exp + 1
                            while last_exp ~= current_exp + 1
                                matrix = [matrix, 0];  
                                last_exp = last_exp - 1;
                            end
                        end
            
                        last_exp = current_exp;
            
                    else
                        if last_exp ~= -1 && last_exp ~= 2
                            while last_exp ~= 2
                                matrix = [matrix, 0];  
                                last_exp = last_exp - 1;
                            end
                        end
                        last_exp = 1;
                    end    


                     s_pos = s_pos(1);
                    if s_pos>2
                        matrix = [matrix, str2double(splitten(1:s_pos-1))];   
                    else  
                        if s_pos==1
                            matrix = [matrix, 1];
                        end
                        if s_pos==2
                            if splitten(1)=="+"
                            matrix = [matrix, 1];
                            end
                            if splitten(1)=="-"
                            matrix = [matrix, -1];
                            end
                            if splitten(1)~="+" && splitten(1)~="-"
                            matrix = [matrix, str2double(splitten(1:s_pos-1))];
                            end

                        end
                    end
                    if i==length(split)
                        if last_exp==1 
                             matrix = [matrix, 0];
                        else
                              while last_exp ~= 0
                                matrix = [matrix, 0];  
                                last_exp = last_exp - 1;
                            end
                        end    
                    end   
                    
                else
                        if last_exp ~= -1 && last_exp ~= 1
                            while last_exp ~= 1
                                matrix = [matrix, 0];  
                                last_exp = last_exp - 1;
                            end
                        end
                      if isnan(str2double(splitten))
                        matrix = [matrix, 0];
                      else
                        matrix = [matrix, str2double(splitten)];
                      end
                end
            
            end
        end

        function [zeros, gain] = string2ZPK_numerator_discrete(app, str)
            split = str; 
            zeros = [];
            gain = 1;
            for i = 1:length(split)
                splitten = split{i};
                z_poz=strfind(splitten, "z");
                if splitten(1)=="("
                    if splitten(z_poz+1)=="-"
                       Zero=splitten(z_poz+2:end-1);
                    else
                        Zero= "-" + string(splitten(z_poz+2:end-1));
                    end
                    Zero=str2double(Zero);
                    if splitten(2)~="z"
                    Zero=Zero/(str2double(splitten(2:z_poz-1)));
                    end    
                    zeros=[zeros,Zero];
                else
                    if ~isempty(z_poz)
                        zeros=[zeros, 0];
                        splitten=erase(splitten, "z");
                    end    
                    gain=str2double(splitten(1:end));
                end    
            end     
        end

        function poles = string2ZPK_denominator_discrete(app, str)
            split = str; 
            poles = [];
            for i = 1:length(split)
                splitten = split{i};
                z_poz=strfind(splitten, "z");
                if splitten(1)=="("
                    if splitten(z_poz+1)=="-"
                       Pole=splitten(z_poz+2:end-1);
                    else
                        Pole= "-" + string(splitten(z_poz+2:end-1));
                    end
                Pole=str2double(Pole);
                if splitten(2)~="z"
                    Pole=Pole/(str2double(splitten(2:z_poz-1)));
                end    
                poles=[poles,Pole];   
                else
                    if ~isempty(z_poz)
                        poles=[poles, 0];
                        splitten=erase(splitten, "z");
                    end 
                end    
            end     
        end

        function [zeros, gain] = string2ZPK_numerator_cont(app, str)
            split = str; 
            zeros = [];
            gain = 1;
            for i = 1:length(split)
                splitten = split{i};
                s_poz=strfind(splitten, "s");
                if splitten(1)=="("
                    if splitten(s_poz+1)=="-"
                       Zero=splitten(s_poz+2:end-1);
                    else
                        Zero= "-" + string(splitten(s_poz+2:end-1));
                    end
                    Zero=str2double(Zero);
                    if splitten(2)~="s"
                    Zero=Zero/(str2double(splitten(2:s_poz-1)));
                    end    
                    zeros=[zeros,Zero];
                else
                    if ~isempty(s_poz)
                        zeros=[zeros, 0];
                        splitten=erase(splitten, "s");
                    end    
                    gain=str2double(splitten(1:end));
                end    
            end     
        end

        function poles = string2ZPK_denominator_cont(app, str)
            split = str; 
            poles = [];
            for i = 1:length(split)
                splitten = split{i};
                s_poz=strfind(splitten, "s");
                if splitten(1)=="("
                    if splitten(s_poz+1)=="-"
                       Pole=splitten(s_poz+2:end-1);
                    else
                        Pole= "-" + string(splitten(s_poz+2:end-1));
                    end
                Pole=str2double(Pole);
                if splitten(2)~="s"
                    Pole=Pole/(str2double(splitten(2:s_poz-1)));
                end    
                poles=[poles,Pole];
                else
                    if ~isempty(s_poz)
                        poles=[poles, 0];
                        splitten=erase(splitten, "s");
                    end 
                end    
            end     
        end
        
        function two_point_algorithm_63_28(app)   
            p1=0.283;
            p2=0.632;
            
            type = app.ChooseinputmetodDropDown.Value;
            if type == "File"
                response=app.sys;
                time=response(:, 1);
                y=response(:,2);
                end_value=y(end);
                begin_value=y(1);
                point1=p1*end_value;
                point2=p2*end_value;  
                [~, idx1] = min(abs(response(:,2) - point1)); 
                [~, idx2] = min(abs(response(:,2) - point2));

            else
                [response, time] = step(app.sys); 
                end_value=response(end);
                begin_value=response(1);
                point1=p1*end_value;
                point2=p2*end_value;  
                [~, idx1] = min(abs(response - point1)); 
                [~, idx2] = min(abs(response - point2));
            end  
           
            t1 = time(idx1); 
            t2 = time(idx2); 
            
            app.k=(end_value-begin_value)/app.du;
            p=log(1-p1)/log(1-p2);
            a=1/log((1-p1)/(1-p2));
            b1=1/(1-p);
            b2=p/(1-p);
            
            app.T=a*(t2-t1);
            app.To=b1*t1-b2*t2;
        end
        
        function two_point_algorithm_70_33(app)
            p1=0.33;
            p2=0.70;
            
            type = app.ChooseinputmetodDropDown.Value;
            if type == "File"
                response=app.sys;
                time=response(:, 1);
                y=response(:,2);
                end_value=y(end);
                begin_value=y(1);
                point1=p1*end_value;
                point2=p2*end_value;  
                [~, idx1] = min(abs(response(:,2) - point1)); 
                [~, idx2] = min(abs(response(:,2) - point2));

            else
                [response, time] = step(app.sys); 
                end_value=response(end);
                begin_value=response(1);
                point1=p1*end_value;
                point2=p2*end_value;  
                [~, idx1] = min(abs(response - point1)); 
                [~, idx2] = min(abs(response - point2));
            end   
                
            t1 = time(idx1); 
            t2 = time(idx2); 
            
            app.k=(end_value-begin_value)/app.du;
            p=log(1-p1)/log(1-p2);
            a=1/log((1-p1)/(1-p2));
            b1=1/(1-p);
            b2=p/(1-p);
            
            app.T=a*(t2-t1);
            app.To=b1*t1-b2*t2;
        end
        
        function two_point_algorithm_85_35(app)
            p1=0.353;
            p2=0.853;

            type = app.ChooseinputmetodDropDown.Value;
            if type == "File"
                response=app.sys;
                time=response(:, 1);
                y=response(:,2);
                end_value=y(end);
                begin_value=y(1);
                point1=p1*end_value;
                point2=p2*end_value;  
                [~, idx1] = min(abs(response(:,2) - point1)); 
                [~, idx2] = min(abs(response(:,2) - point2));

            else
                [response, time] = step(app.sys); 
                end_value=response(end);
                begin_value=response(1);
                point1=p1*end_value;
                point2=p2*end_value;  
                [~, idx1] = min(abs(response - point1)); 
                [~, idx2] = min(abs(response - point2));
            end  
                
            t1 = time(idx1); 
            t2 = time(idx2); 
            
            app.k=(end_value-begin_value)/app.du;
            p=log(1-p1)/log(1-p2);
            a=1/log((1-p1)/(1-p2));
            b1=1/(1-p);
            b2=p/(1-p);
            
            app.T=a*(t2-t1);
            app.To=b1*t1-b2*t2;
        end
        
        function two_point_algorithm_75_25(app)
            p1=0.25;
            p2=0.75;

            type = app.ChooseinputmetodDropDown.Value;
            if type == "File"
                response=app.sys;
                time=response(:, 1);
                y=response(:,2);
                end_value=y(end);
                begin_value=y(1);
                point1=p1*end_value;
                point2=p2*end_value;  
                [~, idx1] = min(abs(response(:,2) - point1)); 
                [~, idx2] = min(abs(response(:,2) - point2));

            else
                [response, time] = step(app.sys); 
                end_value=response(end);
                begin_value=response(1);
                point1=p1*end_value;
                point2=p2*end_value;  
                [~, idx1] = min(abs(response - point1)); 
                [~, idx2] = min(abs(response - point2));
            end  
                
            t1 = time(idx1); 
            t2 = time(idx2); 
            
            app.k=(end_value-begin_value)/app.du;
            p=log(1-p1)/log(1-p2);
            a=1/log((1-p1)/(1-p2));
            b1=1/(1-p);
            b2=p/(1-p);
            
            app.T=a*(t2-t1);
            app.To=b1*t1-b2*t2;
        end
        
        function two_point_algorithm_67_33(app)
            p1=0.33;
            p2=0.67;
            
            type = app.ChooseinputmetodDropDown.Value;
            if type == "File"
                response=app.sys;
                time=response(:, 1);
                y=response(:,2);
                end_value=y(end);
                begin_value=y(1);
                point1=p1*end_value;
                point2=p2*end_value;  
                [~, idx1] = min(abs(response(:,2) - point1)); 
                [~, idx2] = min(abs(response(:,2) - point2));

            else
                [response, time] = step(app.sys); 
                end_value=response(end);
                begin_value=response(1);
                point1=p1*end_value;
                point2=p2*end_value;  
                [~, idx1] = min(abs(response - point1)); 
                [~, idx2] = min(abs(response - point2));
            end  
                
            t1 = time(idx1); 
            t2 = time(idx2); 
            
            app.k=(end_value-begin_value)/app.du;
            p=log(1-p1)/log(1-p2);
            a=1/log((1-p1)/(1-p2));
            b1=1/(1-p);
            b2=p/(1-p);
            
            app.T=a*(t2-t1);
            app.To=b1*t1-b2*t2;
        end
        
        function tangent_metod(app)
            type = app.ChooseinputmetodDropDown.Value;
            if type == "File"
                response=app.sys;
                t=response(:, 1);
                y=response(:,2);             
            else
                [y, t] = step(app.sys);                
            end  
            dy1 = diff(y);      
            dt1 = diff(t);      
            dydt1 = dy1 ./ dt1; 

            dy2 = diff(dy1);      
            dt2 = diff(dt1);      
            dydt2 = dy2 ./ dt2;

            inflection_points = find(diff(sign(dydt2)) ~= 0) + 1;
            valid_inflection_points = inflection_points(y(inflection_points) < 0.3 * y(end));

            best_point_poz=0;
            best_mse=inf;
            app.k=(y(end)-y(1))/app.du;
             for i=1:size(valid_inflection_points)
               x0 = t(inflection_points(i));
               a = interp1(t(2:end), dydt1, x0, 'linear');
               b = interp1(t, y ./t, x0, 'linear') - a*x0;
               p2=(y(end)-b)/a;
               p1=(y(1)-b)/a;
               app.To=p1;
               app.T=p2-p1;
               
     
               model=tf(app.k, [app.T 1]);
               if app.To>0
                 model.InputDelay = app.To;
               end     
               
               [model_response]=step(model, t);
               N=length(model_response);
               mse=sum((y - model_response).^2) / N;

               
               if mse<best_mse
                        best_mse=mse;
                        best_point_poz=i;           
               end    
            end  

               x0 = t(inflection_points(best_point_poz));
               a = interp1(t(2:end), dydt1, x0, 'linear');
               b = interp1(t, y ./t, x0, 'linear') - a*x0;
               end_value=y(end);
               begin_value=y(1);
               p2=(end_value-b)/a;
               p1=(begin_value-b)/a;
               app.To=p1;
               app.T=p2-p1;
            % 
            % for i=1:size(valid_inflection_points)
            %    x0 = t(valid_inflection_points(i));
            %    y0 = y(valid_inflection_points(i));
            %    dydt0 = dydt1(valid_inflection_points(i));
            %    a = dydt0;
            %    tangent = @(t) y0 + a * (t - x0);
            %    p2=tangent(t(end));
            %    p1=tangent(t(1));
            %    app.To=p1;
            %    app.T=p2-p1;
            % 
            % 
            %    model=tf(app.k, [app.T 1]);
            %    if app.To>0
            %      model.InputDelay = app.To;
            %    end     
            % 
            %    [model_response]=step(model, t);
            %    N=length(model_response);
            %    mse=sum((y - model_response).^2) / N;
            % 
            %    if mse<best_mse
            %             best_mse=mse;
            %             best_point_poz=i;           
            %    end    
            % end  
            % 
            %    x0 = t(valid_inflection_points(best_point_poz));
            %    y0 = y(valid_inflection_points(best_point_poz));
            %    a = dydt1(valid_inflection_points(best_point_poz));
            %    tangent = @(t) y0 + a * (t - x0);
            %    p2=tangent(t(end));
            %    p1=tangent(t(1));
            %    app.To=p1;
            %    app.T=p2-p1;
        end

        function Least_Squares_Method_metod(app)
        type = app.ChooseinputmetodDropDown.Value;
        if type == "File"
            response=app.sys;
                t=response(:, 1);
                y=response(:,2);                   
        else
           [y,t] = step(app.sys);                
        end   

        half_value = 0.5 * (y(end) - y(1)) + y(1);  
        T0_index = find(y >= half_value, 1);  
        T0 = t(T0_index); 
        
        app.k=(y(end)-y(1))/app.du; 
        
        model = @(params, t) app.k * (1 - exp(-(t - params(2)) / params(1))) .* (t > params(2));
        
        
        initial_guess = [(t(end) - T0) / 5, T0]; 
        options = optimset('Display', 'off');
        params_opt = lsqcurvefit(@(p, t) model(p, t), initial_guess, t, y, [], [], options);
        
        
        app.T = params_opt(1);
        app.To = params_opt(2);
        end

        function show_Axes(app, response, response_model, time)
            app.UIAxes.Visible = 'on';
            type = app.ChooseinputmetodDropDown.Value;
             if type == "File"
                y=response(:, 2);
                plot(app.UIAxes,time,y, "ro");
            else
                plot(app.UIAxes,time,response, "r");               
            end  
            
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,time,response_model, "b");
            hold(app.UIAxes, 'off');

            title(app.UIAxes, 'Plant and model comparison');
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');
            legend(app.UIAxes, {'Plant', 'Model'});
        end
        
        function choose_metod(app)
            type = app.ChoosemetodDropDown.Value;
            switch type
                case "2point_63_28"
                    two_point_algorithm_63_28(app);
                case "2point_70_33"
                    two_point_algorithm_70_33(app);
                case "2point_85_35"
                    two_point_algorithm_85_35(app);
                case "2point_75_25"
                    two_point_algorithm_75_25(app);
                case "2point_67_33"  
                    two_point_algorithm_67_33(app);
                case "tangent_metod"
                    tangent_metod(app);
                case "Least Squares Method"
                    Least_Squares_Method_metod(app);
            end
        end

        function error_criterion(app, response, response_model, N)
            type = app.ChoosetheerrorcriterionDropDown.Value;
            switch type
                case "MSE"
                    app.ErrorLabel.Text="Mean Squared Error";
                    PredictionError = sum((response - response_model).^2) / N;
                case "RMSE"
                    app.ErrorLabel.Text="Root Mean Squared Error";
                    PredictionError = sqrt(sum((response - response_model).^2) / N);
                case "MEA"
                    app.ErrorLabel.Text="Mean Absolute Error";
                    PredictionError = sum(abs(response - response_model)) / N;
            end

            app.ErrorValue.Visible="on";
            app.ErrorLabel.Visible="on";
            app.ErrorValue.Value=string(PredictionError);
        end    
      
        function Show_results(app)
            app.Delay.Visible = 'off';
            app.s_delay.Visible = 'off';
            app.e_delay.Visible = 'off';          
           
            app.ResultLabel.Visible = 'on';            
            app.s_time_const.Visible = 'on';
            app.Time_const.Visible = 'on';
            app.Fraction_bar.Visible = 'on';
            app.Gain.Visible = 'on';

            app.Gain.Value=string(app.k);
            app.Time_const.Value=string(app.T);
            s=tf('s');
            if app.To>0
                model=app.k/(app.T*s+1)*exp(-app.To*s);
                app.Delay.Visible = 'on';
                app.s_delay.Visible = 'on';
                app.e_delay.Visible = 'on';
                app.Delay.Value=string(app.To);
            else
               model=app.k/(app.T*s+1);
            end   
            
            type = app.ChooseinputmetodDropDown.Value;
            if type == "File"
                response=app.sys;
                time=response(:, 1);
                [response_model,~] = step(model,time);
            else
                [~, t] = step(app.sys);    
                [response_model,t_model] = step(model);

                if t_model(end)>t(end)
                    response=step(app.sys,t_model);
                    time=t_model;
                else
                    response=step(app.sys);
                    [response_model, ~] = step(model,t);
                    time=t;
                end    
            end  
           
            show_Axes(app, response, response_model, time);

            N=length(response);
            if type == "File"
                 y=response(:, 2);
                 error_criterion(app, y, response_model, N);
            else
                 error_criterion(app, response, response_model, N);              
            end  

        end
        
        function [N, D] = Transfer_function(app)
            numerator=app.Numerator.Value;
            denominator=app.Denominator.Value;
            numerator=split(numerator);
            denominator=split(denominator);
            N=string2matrix_tf(app,numerator);
            D=string2matrix_tf(app,denominator);            
        end
        
        function [zeros, poles, gain] = ZPK_descrete_model(app)
            numerator=app.Numerator.Value;
            denominator=app.Denominator.Value;
            numerator=split(numerator);
            denominator=split(denominator);
            [zeros, gain]=string2ZPK_numerator_discrete(app, numerator);
            poles=string2ZPK_denominator_discrete(app,denominator);
        end

        function [zeros, poles, gain] = ZPK_cont_time_model(app)
            numerator=app.Numerator.Value;
            denominator=app.Denominator.Value;
            numerator=split(numerator);
            denominator=split(denominator);
            [zeros, gain]=string2ZPK_numerator_cont(app, numerator);
            poles=string2ZPK_denominator_cont(app,denominator);
        end

        function Import_file(app)
            data = dlmread(string(app.filename));
            y = data(:, 1); 
            t = data(:, 2);
            step_time=app.StepTimeEditField.Value;
            

            start_index = find(t >= step_time, 1);
            t = t(start_index:end);
            y = y(start_index:end);

            fc = 0.1; 
            fs = 1 / mean(diff(t));
            [b, a] = butter(2, fc / (fs / 2)); 
            
            y_filtered = filter(b, a, y);
             
            app.sys = [t, y_filtered];            
        end

        function Fraction_show_on(app)
            app.Numerator.Visible="on";
            app.Label.Visible="on";
            app.Denominator.Visible="on";
            app.Info.Visible="on";
        end
        
        function Fraction_show_off(app)
            app.ZeropolegainmodelLabel.Visible="off";
            app.TransferfunctionLabel.Visible="off";
            app.Numerator.Visible="off";
            app.Label.Visible="off";
            app.Denominator.Visible="off";
            app.Info.Visible="off";
        end
        
        function Imported_file_show_on(app)
            app.StepTimeEditField.Visible="on";
            app.StepTimeEditFieldLabel.Visible="on";
            app.StepValueEditField.Visible="on";
            app.StepValueEditFieldLabel.Visible="on";
            app.ImportfileButton.Visible="on";
        end

        function Imported_file_show_off(app)
            app.StepTimeEditField.Visible="off";
            app.StepTimeEditFieldLabel.Visible="off";
            app.StepValueEditField.Visible="off";
            app.StepValueEditFieldLabel.Visible="off";
            app.ImportfileButton.Visible="off";
        end
      
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ShowresultsButton
        function ShowresultsButtonPushed(app, event)
            type = app.ChooseinputmetodDropDown.Value;
            switch type
                case "Transfer function"
                    [N, D] = Transfer_function(app);
                    app.sys=tf(N,D);    
                    app.du=1;
                case "ZPK descrete"
                    [zeros, poles, gain] = ZPK_descrete_model(app);
                    app.sys = zpk(zeros,poles,gain);
                    app.du=1;
                case "ZPK continuous-time"
                    [zeros, poles, gain] = ZPK_cont_time_model(app);
                    app.sys = zpk(zeros,poles,gain);
                    app.du=1;
                case "File"
                    Import_file(app);
                    app.du=app.StepValueEditField.Value;                        
            end
            choose_metod(app);
            Show_results(app);
        end

        % Value changed function: ChooseinputmetodDropDown
        function ChooseinputmetodDropDownValueChanged(app, event)
            type = app.ChooseinputmetodDropDown.Value;
            switch type
                case "Transfer function"
                    app.TransferfunctionLabel.Visible="on";
                    app.ZeropolegainmodelLabel.Visible="off";
                    Fraction_show_on(app);
                    Imported_file_show_off(app);
                    app.Info.Tooltip="Please you space between each variable. Exapmle: 2s -1/s^2 +s";
                case "ZPK descrete"
                    app.TransferfunctionLabel.Visible="off";
                    app.ZeropolegainmodelLabel.Visible="on";
                    Fraction_show_on(app);
                    Imported_file_show_off(app);
                    app.Info.Tooltip="Please you space between each variable and/or bracket . Exapmle: 2z (z-1)/(z-2) (z+3)";
                case "ZPK continuous-time"
                    app.TransferfunctionLabel.Visible="off";
                    app.ZeropolegainmodelLabel.Visible="on";
                    Fraction_show_on(app);
                    Imported_file_show_off(app);
                    app.Info.Tooltip="Please you space between each variable and/or bracket . Exapmle: 2s (s-1-i)/(s-2+i) (s+3)";
                case "File"    
                    Fraction_show_off(app);
                    Imported_file_show_on(app);
            end
        end

        % Button pushed function: ImportfileButton
        function ImportfileButtonPushed(app, event)
            [app.filename,~]=uigetfile();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {231, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ShowresultsButton = uibutton(app.LeftPanel, 'push');
            app.ShowresultsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowresultsButtonPushed, true);
            app.ShowresultsButton.Text = 'Show results';
            app.ShowresultsButton.Icon = fullfile(pathToMLAPP, 'result.png');
            app.ChoosemetodDropDownLabel = uilabel(app.LeftPanel);
            app.ChoosemetodDropDownLabel.Text = 'Choose metod';
            app.ChoosemetodDropDown = uidropdown(app.LeftPanel);
            app.ChoosemetodDropDown.Items = {'2point_63_28', '2point_70_33', '2point_85_35', '2point_75_25', '2point_67_33', 'tangent_metod', 'Least Squares Method'};
            app.ChoosemetodDropDown.Value = '2point_63_28';
            app.Numerator = uieditfield(app.LeftPanel, 'text');
            app.TransferfunctionLabel = uilabel(app.LeftPanel);
            app.TransferfunctionLabel.Text = 'Transfer function';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '_______________';
            app.Denominator = uieditfield(app.LeftPanel, 'text');
            app.Info = uiimage(app.LeftPanel);
            app.Info.Tooltip = {'Please you space between each variable'; 'Exapmle: 2s -1/s^2 +s'};
            app.Info.ImageSource = fullfile(pathToMLAPP, 'info_17241489.png');
            app.ZeropolegainmodelLabel = uilabel(app.LeftPanel);
            app.ZeropolegainmodelLabel.Visible = 'off';
            app.ZeropolegainmodelLabel.Text = 'Zero-pole-gain model';
            app.ChooseinputmetodLabel = uilabel(app.LeftPanel);
            app.ChooseinputmetodLabel.Text = 'Choose input metod';
            app.ChooseinputmetodDropDown = uidropdown(app.LeftPanel);
            app.ChooseinputmetodDropDown.Items = {'Transfer function', 'ZPK descrete', 'ZPK continuous-time', 'File'};
            app.ChooseinputmetodDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseinputmetodDropDownValueChanged, true);
            app.ChooseinputmetodDropDown.Value = 'Transfer function';
            app.ImportfileButton = uibutton(app.LeftPanel, 'push');
            app.ImportfileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportfileButtonPushed, true);
            app.ImportfileButton.Visible = 'off';
            app.ImportfileButton.Tooltip = {'Choose .txt file with data'};
            app.ImportfileButton.Text = 'Import file';
            app.ImportfileButton.Icon = fullfile(pathToMLAPP, 'import.png');
            app.StepTimeEditFieldLabel = uilabel(app.LeftPanel);
            app.StepTimeEditFieldLabel.Visible = 'off';
            app.StepTimeEditFieldLabel.Text = 'Step Time';
            app.StepTimeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StepTimeEditField.Visible = 'off';
            app.StepValueEditFieldLabel = uilabel(app.LeftPanel);
            app.StepValueEditFieldLabel.Visible = 'off';
            app.StepValueEditFieldLabel.Text = 'Step Value';
            app.StepValueEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StepValueEditField.Visible = 'off';
            app.StepValueEditField.Value = 1;
            app.ChoosetheerrorcriterionDropDownLabel = uilabel(app.LeftPanel);
            app.ChoosetheerrorcriterionDropDownLabel.Text = 'Choose the error criterion';
            app.ChoosetheerrorcriterionDropDown = uidropdown(app.LeftPanel);
            app.ChoosetheerrorcriterionDropDown.Items = {'MSE', 'RMSE', 'MEA'};
            app.ChoosetheerrorcriterionDropDown.Value = 'MSE';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.ResultLabel = uilabel(app.RightPanel);
            app.ResultLabel.Visible = 'off';
            app.ResultLabel.Text = 'Result';
            app.Gain = uieditfield(app.RightPanel, 'text');
            app.Gain.Editable = 'off';
            app.Gain.Visible = 'off';
            app.Fraction_bar = uilabel(app.RightPanel);
            app.Fraction_bar.Visible = 'off';
            app.Fraction_bar.Text = '_______________';
            app.Time_const = uieditfield(app.RightPanel, 'text');
            app.Time_const.Visible = 'off';
            app.s_time_const = uilabel(app.RightPanel);
            app.s_time_const.Visible = 'off';
            app.s_time_const.Text = 's + 1';
            app.e_delay = uilabel(app.RightPanel);
            app.e_delay.Visible = 'off';
            app.e_delay.Text = 'e';
            app.s_delay = uilabel(app.RightPanel);
            app.s_delay.Visible = 'off';
            app.s_delay.Text = '-s';
            app.Delay = uieditfield(app.RightPanel, 'text');
            app.Delay.Visible = 'off';
            app.ErrorValue = uieditfield(app.RightPanel, 'text');
            app.ErrorValue.Editable = 'off';
            app.ErrorValue.Visible = 'off';
            app.ErrorLabel = uilabel(app.RightPanel);
            app.ErrorLabel.Visible = 'off';
            app.ErrorLabel.Text = 'Mean Squared Error';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end